classdef App_Source_Files < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UploadImageButton         matlab.ui.control.Button
        StartLiveDetectionButton  matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        FaceDetectionLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    cam        % Webcam object
    timer      % Timer for live webcam feed
    
    end
  
    methods (Access = private)
        
     function detectFaces(app, img)
    img = imresize(img, [300 300]);

    imginycbcr = rgb2ycbcr(img);
    Cb = imginycbcr(:,:,2);
    Cr = imginycbcr(:,:,3);

    skinrangemask = (Cb >= 77 & Cb <= 130) & (Cr >= 130 & Cr <= 140);

    [rows, ~] = size(skinrangemask);
    upperpartmask = false(size(skinrangemask));
    upperpartmask(1:round(rows*0.8), :) = true;
    skininupperpart = skinrangemask & upperpartmask;

    skininupperpart = imfill(skininupperpart, 'holes');
    skininupperpart = bwareaopen(skininupperpart, 200);

    result = regionprops(skininupperpart, 'BoundingBox', 'Eccentricity');
    face = 0;
    rects = [];

    for i = 1:length(result)
        e = result(i).Eccentricity;
        if e < 0.9
            rect = result(i).BoundingBox;
            facepart = imcrop(img, rect);
            gfacepart = rgb2gray(facepart);
            gfacepart = double(gfacepart);
            gfacepart = (gfacepart - min(gfacepart(:))) / (max(gfacepart(:)) - min(gfacepart(:))) * 255;
            gfacepart = uint8(gfacepart);

            eyespart = gfacepart < 60;
            eyespart = bwareaopen(eyespart, 8);
            eyesresult = regionprops(eyespart, 'BoundingBox');

            nosepart = (gfacepart > 80) & (gfacepart < 120);
            nosepart = bwareaopen(nosepart, 20);
            noseresult = regionprops(nosepart, 'BoundingBox');

            if length(eyesresult) >= 2 && ~isempty(noseresult)
                rects = [rects; rect]; %#ok<AGROW>
                face = 1;
            end
        end
    end

    if face==1
        img = insertShape(img, 'Rectangle', rects, 'Color', 'cyan', 'LineWidth', 3);
      
        app.FaceDetectionLabel.Text = 'Face Detected';
    else
        app.FaceDetectionLabel.Text = 'No Face Detected';
    end

    imshow(img, 'Parent', app.UIAxes);
     end
      function webcamLoop(app)
    if isempty(app.cam)
        return;
    end
    frame = snapshot(app.cam);
    detectFaces(app, frame);
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.cam = webcam;
        end

        % Button pushed function: StartLiveDetectionButton
        function StartLiveDetectionButtonPushed(app, event)
 
    app.timer = timer('ExecutionMode','fixedRate','Period',0.2,...
        'TimerFcn', @(~,~) webcamLoop(app));
    start(app.timer);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
    if ~isempty(app.timer) && isvalid(app.timer)
        stop(app.timer);
        delete(app.timer);
    end
    clear app.cam;
cla(app.UIAxes);
app.FaceDetectionLabel.Text = 'Stopped';
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
             [file, path] = uigetfile({'*.*','All Files (*.*)'});
    if isequal(file, 0)
        return;
    end
    img = imread(fullfile(path, file));
    img = imresize(img, [300 300]);
    imginycbcr = rgb2ycbcr(img);
    Cb = imginycbcr(:,:,2);
    Cr = imginycbcr(:,:,3);

    skinrangemask = (Cb >= 77 & Cb <= 130) & (Cr >= 140 & Cr <= 160);

    [rows, ~] = size(skinrangemask);
    upperpartmask = false(size(skinrangemask));
    upperpartmask(1:round(rows*0.8), :) = true;
    skininupperpart = skinrangemask & upperpartmask;

    skininupperpart = imfill(skininupperpart, 'holes');
    skininupperpart = bwareaopen(skininupperpart, 200);

    result = regionprops(skininupperpart, 'BoundingBox', 'Eccentricity');
    face = 0;
    rects = [];

    for i = 1:length(result)
        e = result(i).Eccentricity;
        if e < 0.9
            rect = result(i).BoundingBox;
            facepart = imcrop(img, rect);
            gfacepart = rgb2gray(facepart);
            gfacepart = double(gfacepart);
            gfacepart = (gfacepart - min(gfacepart(:))) / (max(gfacepart(:)) - min(gfacepart(:))) * 255;
            gfacepart = uint8(gfacepart);

            eyespart = gfacepart < 60;
            eyespart = bwareaopen(eyespart, 8);
            eyesresult = regionprops(eyespart, 'BoundingBox');

            nosepart = (gfacepart > 80) & (gfacepart < 120);
            nosepart = bwareaopen(nosepart, 20);
            noseresult = regionprops(nosepart, 'BoundingBox');

            if length(eyesresult) >= 2 && ~isempty(noseresult)
                rects = [rects; rect]; %#ok<AGROW>
                face = 1;
            end
        end
    end

    if face==1
        img = insertShape(img, 'Rectangle', rects, 'Color', 'cyan', 'LineWidth', 3);
    
        app.FaceDetectionLabel.Text = 'Face Detected';
    else
        app.FaceDetectionLabel.Text = 'No Face Detected';
    end

    imshow(img, 'Parent', app.UIAxes);
   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Icon = 'Upload icon.png';
            app.UploadImageButton.Text = 'Upload Image ';
            app.StartLiveDetectionButton = uibutton(app.UIFigure, 'push');
            app.StartLiveDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartLiveDetectionButtonPushed, true);
            app.StartLiveDetectionButton.Icon = 'Start icon.jpg';
            app.StartLiveDetectionButton.Text = 'Start Live Detection';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FaceDetectionLabel = uilabel(app.UIFigure);
            app.FaceDetectionLabel.Text = 'Face Detection';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Source_Files

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end